classdef GUI_unsupervised < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        InsertButton        matlab.ui.control.StateButton
        InputImageLabel     matlab.ui.control.Label
        SimilarImagesLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InsertButton
        function InsertButtonValueChanged(app, event)
            value = app.InsertButton.Value;
                if value == 1 % if the button is pressed
                    load("Super_SURFNew.mat")
                    [FileName, Path] = uigetfile ('*.bmp; *.png; *.jpg','Select the secret image');  % load picture based on selection
                    queryImage = imread(strcat(Path,FileName));         % read the image
                    app.UIAxes.Title.String = string(FileName);         % change the title name
                    imshow(queryImage,'Parent',app.UIAxes);             % show the selected image to the gui 
                    imageIDs = retrieveImages(queryImage, imageIndex);  % retrieve image 
                    thumbnailGallery = [];                              % list of images
                    for i = 1:9                                         % 6 similar images
                        bestMatch = imageIDs(i);                        % best match according to image ids
                        I = imread(imageIndex.ImageLocation{bestMatch});% read image of the best match
                        thumbnailGallery = cat(4, thumbnailGallery, I); % concatenate to 4 dimension 
                    end
                    app.UIAxes_2.Title.String = string("Similar Items");% change the title to similar items
                    montage(thumbnailGallery,'parent',app.UIAxes_2);    % montage and show the similar image/items
                    
                else
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InsertButton = uibutton(app.UIFigure, 'state');
            app.InsertButton.ValueChangedFcn = createCallbackFcn(app, @InsertButtonValueChanged, true);
            app.InsertButton.Tooltip = {''};
            app.InsertButton.Text = 'Insert';
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.SimilarImagesLabel = uilabel(app.UIFigure);
            app.SimilarImagesLabel.Text = 'Similar Images ';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_unsupervised

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end